classdef Lab10GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout4                    matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        SamplesEditField               matlab.ui.control.NumericEditField
        SamplesEditFieldLabel          matlab.ui.control.Label
        ResistivityAWEditField         matlab.ui.control.NumericEditField
        ResistivityAWEditFieldLabel    matlab.ui.control.Label
        MaxLEDCurrentmAEditField       matlab.ui.control.NumericEditField
        MaxLEDCurrentmAEditFieldLabel  matlab.ui.control.Label
        LEDResistorEditField           matlab.ui.control.NumericEditField
        LEDResistorLabel               matlab.ui.control.Label
        PhotoResistorEditField         matlab.ui.control.NumericEditField
        PhotoResistorLabel             matlab.ui.control.Label
        PhotoAxes                      matlab.ui.control.UIAxes
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout5                    matlab.ui.container.GridLayout
        FileNameEditField              matlab.ui.control.EditField
        FileNameLabel                  matlab.ui.control.Label
        PhotoCurrentChDropDown         matlab.ui.control.DropDown
        PhotoCurrentChDropDownLabel    matlab.ui.control.Label
        LEDCurrentChDropDownLabel      matlab.ui.control.Label
        LEDCurrentChDropDown           matlab.ui.control.DropDown
        RampCurrentChDropDown          matlab.ui.control.DropDown
        RampCurrentChDropDownLabel     matlab.ui.control.Label
        GridLayout3                    matlab.ui.container.GridLayout
        StopButton                     matlab.ui.control.Button
        ClearButton                    matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        %DAQ Variables
        d
        dev
        data

        %Channel Variables
        LEDCurrentCh
        AOChannel
        PCurrentCh
        ch0
        ch1
        ch2
        
        %Data Variables
        R_lambda                            %responsivity derived from Figure 1 in lab manual
        delta_Iout                          %step size of I out based on Imax and LED_Samples
        LED_Vout                            %variable to be populated with outputvoltages
        scanData                            %variable to be populated with data read from DAQ

        %UI Variables
        lamda                               %wavelength of incident light
        sampleRate                          %rate of aquisition in samples/second
        fileName                            %path for data to save to
        I_max                               %current through LED
        photo_R                             %resistor limiting sensor current
        LED_R                               %resistor limiting LED current
        LED_samples                         %resolution for anolog output generation
        i = 1                               %iterator

        %Constants
        Imax_const = 0.030                  %Variable to set max current allowed
        v_LED = 0.6                         %voltage drop across LED


    end
    
    methods (Access = private)
        % Function to populate Analog output array by passing variable LED_vout by refernece %
        function generateAO(app,I_max,LED_R,vout,dI)
            LED_i = 0;
            j = 1;
            while LED_i <= I_max
                vout(1,j) = LED_i * LED_R;
                LED_i = LED_i + dI;
                j = j+1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUpFunc(app)
            app.StopButton.Enable = "off";
            devices = daqlist("ni");                                                                    %creats list of devides
            app.dev = devices(1,"DeviceID");                                                            %saves device name of first daq found in app.dev
            app.d = daq('ni');                                                                          %create data aquisition object, always need this line*** passes to all function that do data aquisition
            app.LEDCurrentCh = app.LEDCurrentChDropDown.Value;                                          %initialize default DAQ input Channel
            app.AOChannel = app.RampCurrentChDropDown.Value;                                            %initialize default DAQ input Channel
            app.d.Rate = 10;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.LEDCurrentChDropDown.Enable = "off";                                                     %disable the front panel options while program is running
            app.RampCurrentChDropDown.Enable = "off";    
            app.ResistivityAWEditField.Enable = "off";
            app.MaxLEDCurrentmAEditField.Enable = "off";
            app.FileNameEditField.Enable = "off";
            app.StartButton.Enable = "off";
            app.StopButton.Enable = "on";                                                               %Enable the stop button
     
            generateAO(app,app.I_max,app.LED_R,app.LED_Vout,app.delta_Iout);                            %populate AO voltage array by reference

            %Assign Channels Based on Front Panel
            app.ch0 = addinput(app.d,app.dev.DeviceID,app.LEDCurrentCh,'Voltage');
            app.ch1 = addinput(app.d,app.dev.DeviceID,app.PCurrentCh,'Voltage');
            app.ch2 = addoutput(app.d,app.dev.DeviceID,app.AOChannel,'Voltage');
            app.ch0.TerminalConfig='Differential';                                                      %Set to differential input
            app.ch1.TerminalConfig='Differential';
            app.i = 1;                                                                                  %reset iterator
            while app.i <= length(app.LED_Vout(1))
                write(app.d,app.LED_Vout(1:app.i));                                                     %Writes data to analog output
                pause 0.1;
                app.scanData = read(app.d);                                                             %Read analog inputs after 100ms delay
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopButton.Enable = false;                                                              %disable stop button to aoid double input

            writetimetable(app.AllDataTimeTable,"Lab08data.csv");                                       %write data to excel
            removechannel(app.d, 1:length(app.d.Channels));                                             %remove channels from daq measurement

            app.LEDCurrentChDropDown.Enable = "on";                                                     %re-enable the front panel options
            app.RampCurrentChDropDown.Enable = "on";    
            app.ResistivityAWEditField.Enable = "on";
            app.MaxLEDCurrentmAEditField.Enable = "on";
            app.FileNameEditField.Enable = "on";
            app.StartButton.Enable = "on";
        end

        % Value changed function: MaxLEDCurrentmAEditField
        function LEDCurrentChanged(app, event)
            if app.MaxLEDCurrentmAEditField.Value >= app.Imax_const
                app.I_max = app.Imax_const;
            else
                app.I_max = app.MaxLEDCurrentmAEditField.Value;               
            end
        end

        % Value changed function: ResistivityAWEditField
        function WavelengthChanged(app, event)
            app.R_lambda = app.ResistivityAWEditField.Value;
        end

        % Value changed function: RampCurrentChDropDown
        function AOChannelChanged(app, event)
            app.AOChannel = app.RampCurrentChDropDown.Value;
        end

        % Value changed function: LEDCurrentChDropDown
        function AIChannelChanged(app, event)
             app.LEDCurrentCh = app.LEDCurrentChDropDown.Value;
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            app.fileName = app.FileNameEditField.Value+'.csv';
        end

        % Value changed function: PhotoCurrentChDropDown
        function PhotoCurrentChDropDownValueChanged(app, event)
            app.PCurrentCh = app.PhotoCurrentChDropDown.Value;
        end

        % Value changed function: PhotoResistorEditField
        function PhotoResistorEditFieldValueChanged(app, event)
            app.photo_R = app.PhotoResistorEditField.Value;
        end

        % Value changed function: LEDResistorEditField
        function LEDResistorEditFieldValueChanged(app, event)
            app.LED_R = app.LEDResistorEditField.Value;
        end

        % Value changed function: SamplesEditField
        function SamplesEditFieldValueChanged(app, event)
            if app.SamplesEditField.Value > 2047
                app.SamplesEditField.Value = 2047;
                app.LED_samples = 2047;
            else
                app.LED_samples = app.SamplesEditField.Value;
            end
            app.delta_Iout = app.I_max / app.LED_samples;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.StartButton = uibutton(app.GridLayout3, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ClearButton = uibutton(app.GridLayout3, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.StopButton = uibutton(app.GridLayout3, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.RampCurrentChDropDownLabel = uilabel(app.GridLayout5);
            app.RampCurrentChDropDownLabel.Text = 'Ramp Current Ch';
            app.RampCurrentChDropDown = uidropdown(app.GridLayout5);
            app.RampCurrentChDropDown.Items = {'ao0', 'ao1'};
            app.RampCurrentChDropDown.ValueChangedFcn = createCallbackFcn(app, @AOChannelChanged, true);
            app.RampCurrentChDropDown.Value = 'ao0';
            app.LEDCurrentChDropDown = uidropdown(app.GridLayout5);
            app.LEDCurrentChDropDown.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.LEDCurrentChDropDown.ValueChangedFcn = createCallbackFcn(app, @AIChannelChanged, true);
            app.LEDCurrentChDropDown.Value = 'ai0';
            app.LEDCurrentChDropDownLabel = uilabel(app.GridLayout5);
            app.LEDCurrentChDropDownLabel.Text = 'LED Current Ch';
            app.PhotoCurrentChDropDownLabel = uilabel(app.GridLayout5);
            app.PhotoCurrentChDropDownLabel.Text = 'Photo Current Ch';
            app.PhotoCurrentChDropDown = uidropdown(app.GridLayout5);
            app.PhotoCurrentChDropDown.Items = {'ai0', 'ai1', 'ai2', 'ai3', 'ai4', 'ai5', 'ai6', 'ai7'};
            app.PhotoCurrentChDropDown.ValueChangedFcn = createCallbackFcn(app, @PhotoCurrentChDropDownValueChanged, true);
            app.PhotoCurrentChDropDown.Value = 'ai1';
            app.FileNameLabel = uilabel(app.GridLayout5);
            app.FileNameLabel.Text = 'File Name:';
            app.FileNameEditField = uieditfield(app.GridLayout5, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FileNameEditField.Value = 'C:\Users\woollastoncb\Desktop\Lab10_photodiodeData';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.PhotoAxes = uiaxes(app.GridLayout4);
            title(app.PhotoAxes, 'Photodiode Current vs LED Current')
            xlabel(app.PhotoAxes, 'LED Current [mA]')
            ylabel(app.PhotoAxes, 'Photodiode Current [mA]')
            zlabel(app.PhotoAxes, 'Z')
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.PhotoResistorLabel = uilabel(app.GridLayout6);
            app.PhotoResistorLabel.Text = 'Photo Resistor [Ω]';
            app.PhotoResistorEditField = uieditfield(app.GridLayout6, 'numeric');
            app.PhotoResistorEditField.Limits = [0 1000];
            app.PhotoResistorEditField.ValueChangedFcn = createCallbackFcn(app, @PhotoResistorEditFieldValueChanged, true);
            app.PhotoResistorEditField.Value = 250;
            app.LEDResistorLabel = uilabel(app.GridLayout6);
            app.LEDResistorLabel.Text = 'LED Resistor [Ω]';
            app.LEDResistorEditField = uieditfield(app.GridLayout6, 'numeric');
            app.LEDResistorEditField.Limits = [0 Inf];
            app.LEDResistorEditField.ValueDisplayFormat = '%.0f';
            app.LEDResistorEditField.ValueChangedFcn = createCallbackFcn(app, @LEDResistorEditFieldValueChanged, true);
            app.LEDResistorEditField.Value = 20000;
            app.MaxLEDCurrentmAEditFieldLabel = uilabel(app.GridLayout6);
            app.MaxLEDCurrentmAEditFieldLabel.Text = 'Max LED Current [mA]';
            app.MaxLEDCurrentmAEditField = uieditfield(app.GridLayout6, 'numeric');
            app.MaxLEDCurrentmAEditField.Limits = [0 30];
            app.MaxLEDCurrentmAEditField.ValueChangedFcn = createCallbackFcn(app, @LEDCurrentChanged, true);
            app.MaxLEDCurrentmAEditField.Value = 30;
            app.ResistivityAWEditFieldLabel = uilabel(app.GridLayout6);
            app.ResistivityAWEditFieldLabel.Text = 'Resistivity [A/W]';
            app.ResistivityAWEditField = uieditfield(app.GridLayout6, 'numeric');
            app.ResistivityAWEditField.Limits = [0 1000000000];
            app.ResistivityAWEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthChanged, true);
            app.ResistivityAWEditField.Value = 520;
            app.SamplesEditFieldLabel = uilabel(app.GridLayout6);
            app.SamplesEditFieldLabel.Text = 'Samples';
            app.SamplesEditField = uieditfield(app.GridLayout6, 'numeric');
            app.SamplesEditField.ValueChangedFcn = createCallbackFcn(app, @SamplesEditFieldValueChanged, true);
            app.SamplesEditField.Value = 2047;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab10GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartUpFunc)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end